classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        WestCambridgeDeliveryMapPanel  matlab.ui.container.Panel
        DroneXEditFieldLabel           matlab.ui.control.Label
        DroneXEditField                matlab.ui.control.EditField
        DeliveryEditFieldLabel         matlab.ui.control.Label
        DeliveryEditField              matlab.ui.control.EditField
        UploadMissionButton            matlab.ui.control.Button
        DroneYEditFieldLabel           matlab.ui.control.Label
        DroneYEditField                matlab.ui.control.EditField
        PickupEditFieldLabel           matlab.ui.control.Label
        PickupEditField                matlab.ui.control.EditField
    end

    
    properties (Access = private)
        gx;
        droneMarker;
    end
    
    methods (Access = public)
        function updateGUI(app, varargin)
            % create objects to get runtime values of blocks in Simulink
            rto0 = get_param([bdroot,'/Guidance Logic/Full Guidance Logic/dispX'],'RuntimeObject');
            rto1 = get_param([bdroot,'/Guidance Logic/Full Guidance Logic/dispY'],'RuntimeObject');
            
            % update GUI
            app.DroneXEditField.Value = string(rto0.InputPort(1).Data);
            app.DroneYEditField.Value = string(rto1.InputPort(1).Data);
            
            CamCenter = [52.2116,0.0869];
            tempOffset = -[-186,-105];
            dronePos = CamCenter + (tempOffset + [rto0.InputPort(1).Data,rto1.InputPort(1).Data])/(40*10^6) * 1000;
            app.gx.MapCenter = dronePos;
            app.droneMarker.LatitudeData = dronePos(1);
            app.droneMarker.LongitudeData = dronePos(2);
            hold(app.gx,"on");
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            CamCenter = [52.2116,0.0869];
            app.gx = geoaxes(app.WestCambridgeDeliveryMapPanel ,"Basemap","streets","MapCenter",CamCenter,"ZoomLevel",15);
            hold(app.gx,"on");
            app.droneMarker = geoplot(app.gx,0,0,'or');
            hold(app.gx,"on");
            
            % temp line plot test below
            WGates = [52.210925,0.092022];
            Cavendish = [52.2093478,0.093167];
            geoplot(app.gx,[WGates(1),Cavendish(1)],[WGates(2),Cavendish(2)],"LineWidth",2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WestCambridgeDeliveryMapPanel = uipanel(app.UIFigure);
            app.WestCambridgeDeliveryMapPanel.Title = 'West Cambridge Delivery Map';
            app.DroneXEditFieldLabel = uilabel(app.UIFigure);
            app.DroneXEditFieldLabel.Text = 'DroneX';
            app.DroneXEditField = uieditfield(app.UIFigure, 'text');
            app.DeliveryEditFieldLabel = uilabel(app.UIFigure);
            app.DeliveryEditFieldLabel.Text = 'Delivery';
            app.DeliveryEditField = uieditfield(app.UIFigure, 'text');
            app.UploadMissionButton = uibutton(app.UIFigure, 'push');
            app.UploadMissionButton.Text = {'Upload'; 'Mission'};
            app.DroneYEditFieldLabel = uilabel(app.UIFigure);
            app.DroneYEditFieldLabel.Text = 'DroneY';
            app.DroneYEditField = uieditfield(app.UIFigure, 'text');
            app.PickupEditFieldLabel = uilabel(app.UIFigure);
            app.PickupEditFieldLabel.Text = 'Pickup';
            app.PickupEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end